classdef ELEC_291_Project_1_Reflow_Oven_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlWithMATLABCheckBox  matlab.ui.control.CheckBox
        TextArea_2                 matlab.ui.control.TextArea
        DebugDisplay_Receive       matlab.ui.control.TextArea
        DebugDisplay               matlab.ui.control.TextArea
        CLOSEButton                matlab.ui.control.Button
        ErrorTimeoutsSlider        matlab.ui.control.Slider
        ErrorTimeoutsSliderLabel   matlab.ui.control.Label
        ErrorTimeoutsSpinner       matlab.ui.control.Spinner
        ReflowTimesSlider          matlab.ui.control.Slider
        ReflowTimesSpinner         matlab.ui.control.Spinner
        ReflowTimesSliderLabel     matlab.ui.control.Label
        ReflowTempCSlider          matlab.ui.control.Slider
        ReflowTempCSliderLabel     matlab.ui.control.Label
        ReflowTemperatureSpinner   matlab.ui.control.Spinner
        SoakingTimesSlider         matlab.ui.control.Slider
        SoakingTimeLabel           matlab.ui.control.Label
        SoakingTimesSpinner        matlab.ui.control.Spinner
        SoakingTempCSlider         matlab.ui.control.Slider
        SampleCountLabel           matlab.ui.control.Label
        SoakingTemperatureSpinner  matlab.ui.control.Spinner
        Lamp                       matlab.ui.control.Lamp
        ProjectLabel               matlab.ui.control.Label
        OPENButton                 matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        STARTButton                matlab.ui.control.Button
        ABORTButton                matlab.ui.control.Button
        RefreshButton              matlab.ui.control.Button
        BuadRateEditField          matlab.ui.control.NumericEditField
        BuadRateEditFieldLabel     matlab.ui.control.Label
        COMPortDropDown            matlab.ui.control.DropDown
        COMPortDropDownLabel       matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serialportopen
        abortflag
        currenttemp
        ovenstatus
        state
    end
    
    methods (Access = private)
        
        function ClosePortSR(app)
            StopOven(app);
            app.abortflag = 1;
            app.serialportopen = 0;

            app.TextArea.Value = {'No COM port opened.'};
            app.BuadRateEditField.Enable = 'on';
            app.RefreshButton.Enable = 'on';
            app.COMPortDropDown.Enable = 'on';

            app.OPENButton.Visible = 'on';
            app.CLOSEButton.Visible = 'off';

            app.STARTButton.Visible = 'off';
            app.ABORTButton.Visible = 'off';
            app.Lamp.Visible = "off";
            app.TextArea_2.Visible = "off";

            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end
        
        function StartOven(app)
            app.ovenstatus = '1';
            app.ABORTButton.Visible = 'on';
            app.STARTButton.Visible = 'on';
            SendSerial(app);
        end

        function StopOven(app)
            app.ovenstatus = '0';
            app.ABORTButton.Visible = 'on';
            app.STARTButton.Visible = 'on';
            SendSerial(app);
        end

        function OpenPortSR(app)
            app.TextArea.Value = {'COM port sucessfully opened.'};
            app.BuadRateEditField.Enable = 'off';
            app.RefreshButton.Enable = 'off';
            app.COMPortDropDown.Enable = 'off';

            app.OPENButton.Visible = 'off';
            app.CLOSEButton.Visible = 'on';

            app.STARTButton.Visible = 'on';
            app.ABORTButton.Visible = 'on';
            app.Lamp.Visible = "on";
            app.TextArea_2.Visible = "on";

            SendSerial(app);
        end

        function StartDataLog(app)
            app.abortflag = 0;
            a = plot(app.UIAxes,1,1);
            delete(a);
            if app.serialportopen ~= 0
                flush(app.serialportopen);
                x = 1:1000000;
                y = 0*(1:1000000);
                hLine = plot(app.UIAxes,x,y);
                for i=1:1000000
                    if app.abortflag == 1
                        break;
                    end
                    flush(app.serialportopen);
                    str = read(app.serialportopen, 9, "uint8");
                    app.DebugDisplay_Receive.Value = char(str);
                    y(i) = (str(1)*256^3+str(2)*256^2+str(3)*256+str(4))/100;
                    app.SoakingTemperatureSpinner.Value = double(bitand(str(5),0x0F)) + 10 * double(bitand(str(5),0xF0)) / 16 + 100;
                    app.SoakingTempCSlider.Value = app.SoakingTemperatureSpinner.Value;
                    app.SoakingTimesSpinner.Value = double(bitand(str(6),0x0F)) + 10 * double(bitand(str(6),0xF0)) / 16;
                    app.SoakingTimesSlider.Value = app.SoakingTimesSpinner.Value;
                    app.ReflowTemperatureSpinner.Value = double(bitand(str(7),0x0F)) + 10 * double(bitand(str(7),0xF0)) / 16 + 200;
                    app.ReflowTempCSlider.Value = app.ReflowTemperatureSpinner.Value;
                    app.ReflowTimesSpinner.Value = double(bitand(str(8),0x0F)) + 10 * double(bitand(str(8),0xF0)) / 16;
                    app.ReflowTimesSlider.Value = app.ReflowTimesSpinner.Value;
                    app.TextArea_2.Value = app.state(double(bitand(str(9),0x0F)) + 1);
                    if double(bitand(str(9),0x0F)) > 0
                        app.Lamp.Color = "Green";
                    else
                        app.Lamp.Color = "Black";
                    end
                    stripchart('Update',hLine,y(i));
                end
            end
        end

        function SendSerial(app)
            b = char(app.SoakingTemperatureSpinner.Value-100);
            c = char(app.SoakingTimesSpinner.Value);
            d = char(app.ReflowTemperatureSpinner.Value-200);
            e = char(app.ReflowTimesSpinner.Value);
            f = char(app.ErrorTimeoutsSpinner.Value);
            g = char(app.ovenstatus);
            sendstring = [b,c,d,e,f,g];
            if app.serialportopen ~= 0
                if app.ControlWithMATLABCheckBox.Value ~= 0
                    write(app.serialportopen,sendstring,"char");
                end
            end
            app.DebugDisplay.Value = sendstring;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
            app.ovenstatus = '0';
            SendSerial(app);
            app.state = ["Standby", "Heating", "Soaking", "Reflow", "Cooldown"];
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            StartOven(app);
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            if (strfind(app.COMPortDropDown.Value,'COM') ~= 0)
                if (app.BuadRateEditField.Value > 0)
                    app.serialportopen = serialport(app.COMPortDropDown.Value,app.BuadRateEditField.Value);
                    OpenPortSR(app);
                    StartDataLog(app);
                else
                    app.TextArea.Value = {'Baud rate cannot be negative.'};
                end
            else    
                app.TextArea.Value = {'No valid COM Port found.'};
            end
            
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            StopOven(app);
        end

        % Value changed function: SoakingTemperatureSpinner
        function SoakingTemperatureSpinnerValueChanged(app, event)
            app.SoakingTempCSlider.Value = app.SoakingTemperatureSpinner.Value;
            SendSerial(app);
        end

        % Value changed function: SoakingTempCSlider
        function SoakingTempCSliderValueChanged(app, event)
            app.SoakingTempCSlider.Value = round(app.SoakingTempCSlider.Value);
            app.SoakingTemperatureSpinner.Value = app.SoakingTempCSlider.Value;
            SendSerial(app);
        end

        % Value changed function: SoakingTimesSpinner
        function SoakingTimesSpinnerValueChanged(app, event)
            app.SoakingTimesSlider.Value = app.SoakingTimesSpinner.Value;
            SendSerial(app);
        end

        % Value changed function: ReflowTemperatureSpinner
        function ReflowTemperatureSpinnerValueChanged(app, event)
            app.ReflowTempCSlider.Value = app.ReflowTemperatureSpinner.Value;
            SendSerial(app);
        end

        % Value changed function: ReflowTimesSpinner
        function ReflowTimesSpinnerValueChanged(app, event)
            app.ReflowTimesSlider.Value = app.ReflowTimesSpinner.Value;
            SendSerial(app);
        end

        % Value changed function: ErrorTimeoutsSpinner
        function ErrorTimeoutsSpinnerValueChanged(app, event)
            app.ErrorTimeoutsSlider.Value = app.ErrorTimeoutsSpinner.Value;
            SendSerial(app);
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            app.abortflag = 1;
            ClosePortSR(app);
        end

        % Value changed function: SoakingTimesSlider
        function SoakingTimesSliderValueChanged(app, event)
            app.SoakingTimesSlider.Value = round(app.SoakingTimesSlider.Value);
            app.SoakingTimesSpinner.Value = app.SoakingTimesSlider.Value;
            SendSerial(app);
        end

        % Value changed function: ReflowTempCSlider
        function ReflowTempCSliderValueChanged(app, event)
            app.ReflowTempCSlider.Value = round(app.ReflowTempCSlider.Value);
            app.ReflowTemperatureSpinner.Value = app.ReflowTempCSlider.Value;
            SendSerial(app);
        end

        % Value changed function: ReflowTimesSlider
        function ReflowTimesSliderValueChanged(app, event)
            app.ReflowTimesSlider.Value = round(app.ReflowTimesSlider.Value);
            app.ReflowTimesSpinner.Value = app.ReflowTimesSlider.Value;
            SendSerial(app);
        end

        % Value changed function: ErrorTimeoutsSlider
        function ErrorTimeoutsSliderValueChanged(app, event)
            app.ErrorTimeoutsSlider.Value = round(app.ErrorTimeoutsSlider.Value);
            app.ErrorTimeoutsSpinner.Value = app.ErrorTimeoutsSlider.Value;
            SendSerial(app);
        end

        % Value changed function: ControlWithMATLABCheckBox
        function ControlWithMATLABCheckBoxValueChanged(app, event)
            if app.ControlWithMATLABCheckBox.Value == 1
                app.ErrorTimeoutsSlider.Enable = "on";
                app.ErrorTimeoutsSpinner.Enable = "on";
                app.ReflowTempCSlider.Enable = "on";
                app.ReflowTemperatureSpinner.Enable = "on";
                app.SoakingTempCSlider.Enable = "on";
                app.SoakingTemperatureSpinner.Enable = "on";
                app.ReflowTimesSlider.Enable = "on";
                app.ReflowTimesSpinner.Enable = "on";
                app.SoakingTimesSlider.Enable = "on";
                app.SoakingTimesSpinner.Enable = "on";
                app.STARTButton.Enable = "on";
                app.ABORTButton.Enable = "on";
            end
            if app.ControlWithMATLABCheckBox.Value == 0
                app.ErrorTimeoutsSlider.Enable = "off";
                app.ErrorTimeoutsSpinner.Enable = "off";
                app.ReflowTempCSlider.Enable = "off";
                app.ReflowTemperatureSpinner.Enable = "off";
                app.SoakingTempCSlider.Enable = "off";
                app.SoakingTemperatureSpinner.Enable = "off";
                app.ReflowTimesSlider.Enable = "off";
                app.ReflowTimesSpinner.Enable = "off";
                app.SoakingTimesSlider.Enable = "off";
                app.SoakingTimesSpinner.Enable = "off";
                app.STARTButton.Enable = "off";
                app.ABORTButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature Plot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temperature (°C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [999700 1000000];
            app.UIAxes.XTickLabel = {'-300'; '-250'; '-200'; '-150'; '-100'; '-50'; '0'};
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {''};
            app.COMPortDropDown.Value = '';
            app.BuadRateEditFieldLabel = uilabel(app.UIFigure);
            app.BuadRateEditFieldLabel.Text = 'Buad Rate';
            app.BuadRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuadRateEditField.ValueDisplayFormat = '%11.4d';
            app.BuadRateEditField.Value = 115200;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ABORTButton = uibutton(app.UIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Enable = 'off';
            app.ABORTButton.Visible = 'off';
            app.ABORTButton.Text = 'ABORT';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Visible = 'off';
            app.STARTButton.Text = 'START';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No COM port opened.'};
            app.OPENButton = uibutton(app.UIFigure, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.ProjectLabel = uilabel(app.UIFigure);
            app.ProjectLabel.Text = {'ELEC 291 - Electrical Engineering Design Studio I'; 'Project 1 - Reflow Oven Controller'; 'Group B7 - 2024.'};
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.SoakingTemperatureSpinner = uispinner(app.UIFigure);
            app.SoakingTemperatureSpinner.Limits = [100 199];
            app.SoakingTemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @SoakingTemperatureSpinnerValueChanged, true);
            app.SoakingTemperatureSpinner.Enable = 'off';
            app.SoakingTemperatureSpinner.Value = 160;
            app.SampleCountLabel = uilabel(app.UIFigure);
            app.SampleCountLabel.Text = {'Soaking'; 'Temp (°C)'};
            app.SoakingTempCSlider = uislider(app.UIFigure);
            app.SoakingTempCSlider.Limits = [100 199];
            app.SoakingTempCSlider.ValueChangedFcn = createCallbackFcn(app, @SoakingTempCSliderValueChanged, true);
            app.SoakingTempCSlider.Enable = 'off';
            app.SoakingTempCSlider.Value = 160;
            app.SoakingTimesSpinner = uispinner(app.UIFigure);
            app.SoakingTimesSpinner.Limits = [0 99];
            app.SoakingTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @SoakingTimesSpinnerValueChanged, true);
            app.SoakingTimesSpinner.Enable = 'off';
            app.SoakingTimesSpinner.Value = 70;
            app.SoakingTimeLabel = uilabel(app.UIFigure);
            app.SoakingTimeLabel.Text = {'Soaking'; 'Time (s)'};
            app.SoakingTimesSlider = uislider(app.UIFigure);
            app.SoakingTimesSlider.Limits = [0 99];
            app.SoakingTimesSlider.ValueChangedFcn = createCallbackFcn(app, @SoakingTimesSliderValueChanged, true);
            app.SoakingTimesSlider.Enable = 'off';
            app.SoakingTimesSlider.Value = 70;
            app.ReflowTemperatureSpinner = uispinner(app.UIFigure);
            app.ReflowTemperatureSpinner.Limits = [200 240];
            app.ReflowTemperatureSpinner.ValueChangedFcn = createCallbackFcn(app, @ReflowTemperatureSpinnerValueChanged, true);
            app.ReflowTemperatureSpinner.Enable = 'off';
            app.ReflowTemperatureSpinner.Value = 220;
            app.ReflowTempCSliderLabel = uilabel(app.UIFigure);
            app.ReflowTempCSliderLabel.Text = {'Reflow'; 'Temp (°C)'};
            app.ReflowTempCSlider = uislider(app.UIFigure);
            app.ReflowTempCSlider.Limits = [200 240];
            app.ReflowTempCSlider.ValueChangedFcn = createCallbackFcn(app, @ReflowTempCSliderValueChanged, true);
            app.ReflowTempCSlider.Enable = 'off';
            app.ReflowTempCSlider.Value = 220;
            app.ReflowTimesSliderLabel = uilabel(app.UIFigure);
            app.ReflowTimesSliderLabel.Text = {'Reflow'; 'Time (s)'};
            app.ReflowTimesSpinner = uispinner(app.UIFigure);
            app.ReflowTimesSpinner.Limits = [0 60];
            app.ReflowTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @ReflowTimesSpinnerValueChanged, true);
            app.ReflowTimesSpinner.Enable = 'off';
            app.ReflowTimesSpinner.Value = 30;
            app.ReflowTimesSlider = uislider(app.UIFigure);
            app.ReflowTimesSlider.Limits = [0 60];
            app.ReflowTimesSlider.ValueChangedFcn = createCallbackFcn(app, @ReflowTimesSliderValueChanged, true);
            app.ReflowTimesSlider.Enable = 'off';
            app.ReflowTimesSlider.Value = 30;
            app.ErrorTimeoutsSpinner = uispinner(app.UIFigure);
            app.ErrorTimeoutsSpinner.Limits = [0 99];
            app.ErrorTimeoutsSpinner.ValueChangedFcn = createCallbackFcn(app, @ErrorTimeoutsSpinnerValueChanged, true);
            app.ErrorTimeoutsSpinner.Enable = 'off';
            app.ErrorTimeoutsSpinner.Value = 60;
            app.ErrorTimeoutsSliderLabel = uilabel(app.UIFigure);
            app.ErrorTimeoutsSliderLabel.Text = {'Error'; 'Timeout (s)'};
            app.ErrorTimeoutsSlider = uislider(app.UIFigure);
            app.ErrorTimeoutsSlider.Limits = [0 99];
            app.ErrorTimeoutsSlider.ValueChangedFcn = createCallbackFcn(app, @ErrorTimeoutsSliderValueChanged, true);
            app.ErrorTimeoutsSlider.Enable = 'off';
            app.ErrorTimeoutsSlider.Value = 60;
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Visible = 'off';
            app.CLOSEButton.Text = 'CLOSE';
            app.DebugDisplay = uitextarea(app.UIFigure);
            app.DebugDisplay.Editable = 'off';
            app.DebugDisplay.Visible = 'off';
            app.DebugDisplay_Receive = uitextarea(app.UIFigure);
            app.DebugDisplay_Receive.Editable = 'off';
            app.DebugDisplay_Receive.Visible = 'off';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.ControlWithMATLABCheckBox = uicheckbox(app.UIFigure);
            app.ControlWithMATLABCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlWithMATLABCheckBoxValueChanged, true);
            app.ControlWithMATLABCheckBox.Text = 'Control With MATLAB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELEC_291_Project_1_Reflow_Oven_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end